classdef SettingsWheel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManageSharingUIFigure  matlab.ui.Figure
        CurrentlySharedLabel   matlab.ui.control.Label
        UITable                matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            row1 = ["My Super Cool Science", "Jack", "2/20/2021", "Read-only"];
            row2 = ["My Super Cool Math", "Joe", "2/10/2021", "Edit"];
            row3 = ["Just a fiddle", "Ned", "3/17/2020", "Edit"];
            row4 = ["Phone Tone", "Loren", "1/12/2019", "Edit"];
            row5 = ["MATLAB Rocks", "Michelle", "5/10/2019", "Edit"];
            tData = [row1; row2; row3; row4; row5];

            app.UITable.Data = tData;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManageSharingUIFigure = uifigure('Visible', 'off');
            app.ManageSharingUIFigure.Name = 'Manage Sharing';
            app.UITable = uitable(app.ManageSharingUIFigure);
            app.UITable.ColumnName = {'Name'; 'Shared By'; 'Shared On'; 'Access'};
            app.UITable.RowName = {};
            app.CurrentlySharedLabel = uilabel(app.ManageSharingUIFigure);
            app.CurrentlySharedLabel.Text = 'Currently Shared';
            % Show the figure after all components are created
            app.ManageSharingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SettingsWheel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManageSharingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManageSharingUIFigure)
        end
    end
end