classdef ShareHub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShareHubUIFigure              matlab.ui.Figure
        CurrentlySharedLabel          matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        Image                         matlab.ui.control.Image
        MATLABShareHubLabel           matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        ShareTab                      matlab.ui.container.Tab
        Image8                        matlab.ui.control.Image
        CopyLinkButton                matlab.ui.control.Button
        WhocanusethislinkButtonGroup  matlab.ui.container.ButtonGroup
        AllowEditingCheckBox          matlab.ui.control.CheckBox
        SpecificpeopleButton          matlab.ui.control.RadioButton
        PeopleinmyorganizationButton  matlab.ui.control.RadioButton
        AnyoneButton                  matlab.ui.control.RadioButton
        SendButton                    matlab.ui.control.Button
        WhattoShareDropDown           matlab.ui.control.DropDown
        WhattoShareDropDownLabel      matlab.ui.control.Label
        CommunityTab                  matlab.ui.container.Tab
        AskForm                       matlab.ui.control.Image
        FEUpload2                     matlab.ui.control.Image
        MAButton                      matlab.ui.control.Button
        FEImage                       matlab.ui.control.Image
        MABlueIcon                    matlab.ui.control.Image
        FEUpload1                     matlab.ui.control.Image
        SocialTab                     matlab.ui.container.Tab
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        SupportTab                    matlab.ui.container.Tab
        Image7                        matlab.ui.control.Image
    end


    properties (Access = private)
        showingTable % Description 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.showingTable = false;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            %SettingsWheel;

            if app.showingTable == true
                app.showingTable = false;
                app.TabGroup.Visible = true;
                app.UITable.Visible = false;
                app.CurrentlySharedLabel.Visible = false;    
            else
                app.TabGroup.Visible = false;

                row1 = ["My Super Cool Science", "Jack", "2/20/2021", "Read-only"];
                row2 = ["My Super Cool Math", "Joe", "2/10/2021", "Edit"];
                row3 = ["Just a fiddle", "Ned", "3/17/2020", "Edit"];
                row4 = ["Phone Tone", "Loren", "1/12/2019", "Edit"];
                row5 = ["MATLAB Rocks", "Michelle", "5/10/2019", "Edit"];
                tData = [row1; row2; row3; row4; row5];

                app.UITable.Data = tData;
                app.UITable.Visible = true;
                app.CurrentlySharedLabel.Visible = true;    

                app.showingTable = true;
            end

        end

        % Image clicked function: FEUpload1
        function FEUpload1Clicked(app, event)
            app.MABlueIcon.Visible = false;
            app.MAButton.Visible = false;
            app.FEUpload1.Visible = false;
            app.FEUpload2.Visible = true;

        end

        % Image clicked function: FEImage
        function FEImageClicked(app, event)
            app.MABlueIcon.Visible = true;
            app.MAButton.Visible = true;
            app.FEUpload1.Visible = true;
            app.FEUpload2.Visible = false;
        end

        % Button pushed function: MAButton
        function MAButtonPushed(app, event)
            app.AskForm.Visible = true;
        end

        % Image clicked function: AskForm
        function AskFormImageClicked(app, event)
            app.AskForm.Visible = false;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShareHubUIFigure = uifigure('Visible', 'off');
            app.ShareHubUIFigure.Name = 'Share Hub';
            app.TabGroup = uitabgroup(app.ShareHubUIFigure);
            app.ShareTab = uitab(app.TabGroup);
            app.ShareTab.Title = 'Share';
            app.WhattoShareDropDownLabel = uilabel(app.ShareTab);
            app.WhattoShareDropDownLabel.Text = 'What to Share';
            app.WhattoShareDropDown = uidropdown(app.ShareTab);
            app.WhattoShareDropDown.Items = {'MyAwesomeScript.m', 'Option 2', 'Option 3', 'Option 4'};
            app.WhattoShareDropDown.Value = 'MyAwesomeScript.m';
            app.SendButton = uibutton(app.ShareTab, 'push');
            app.SendButton.Text = 'Send';
            app.WhocanusethislinkButtonGroup = uibuttongroup(app.ShareTab);
            app.WhocanusethislinkButtonGroup.Title = 'Who can use this link';
            app.AnyoneButton = uiradiobutton(app.WhocanusethislinkButtonGroup);
            app.AnyoneButton.Text = 'Anyone';
            app.AnyoneButton.Value = true;
            app.PeopleinmyorganizationButton = uiradiobutton(app.WhocanusethislinkButtonGroup);
            app.PeopleinmyorganizationButton.Text = 'People in my organization';
            app.SpecificpeopleButton = uiradiobutton(app.WhocanusethislinkButtonGroup);
            app.SpecificpeopleButton.Text = 'Specific people';
            app.AllowEditingCheckBox = uicheckbox(app.WhocanusethislinkButtonGroup);
            app.AllowEditingCheckBox.Text = 'Allow Editing';
            app.CopyLinkButton = uibutton(app.ShareTab, 'push');
            app.CopyLinkButton.Icon = 'copylink.png';
            app.CopyLinkButton.Text = 'Copy Link';
            app.Image8 = uiimage(app.ShareTab);
            app.Image8.ImageSource = 'folderbrowser.png';
            app.CommunityTab = uitab(app.TabGroup);
            app.CommunityTab.Title = 'Community';
            app.FEUpload1 = uiimage(app.CommunityTab);
            app.FEUpload1.ImageClickedFcn = createCallbackFcn(app, @FEUpload1Clicked, true);
            app.FEUpload1.ImageSource = 'UploadFE.png';
            app.MABlueIcon = uiimage(app.CommunityTab);
            app.MABlueIcon.ImageSource = 'matlabanswer.png';
            app.FEImage = uiimage(app.CommunityTab);
            app.FEImage.ImageClickedFcn = createCallbackFcn(app, @FEImageClicked, true);
            app.FEImage.ImageSource = 'FEx.png';
            app.MAButton = uibutton(app.CommunityTab, 'push');
            app.MAButton.ButtonPushedFcn = createCallbackFcn(app, @MAButtonPushed, true);
            app.MAButton.Icon = 'askquestion.png';
            app.MAButton.Text = '';
            app.FEUpload2 = uiimage(app.CommunityTab);
            app.FEUpload2.Visible = 'off';
            app.FEUpload2.ImageSource = 'FEUpload2.png';
            app.AskForm = uiimage(app.CommunityTab);
            app.AskForm.ImageClickedFcn = createCallbackFcn(app, @AskFormImageClicked, true);
            app.AskForm.Visible = 'off';
            app.AskForm.ImageSource = 'AskForm.png';
            app.SocialTab = uitab(app.TabGroup);
            app.SocialTab.Title = 'Social';
            app.Image2 = uiimage(app.SocialTab);
            app.Image2.ImageSource = 'facebook.png';
            app.Image3 = uiimage(app.SocialTab);
            app.Image3.ImageSource = 'twitter.png';
            app.SupportTab = uitab(app.TabGroup);
            app.SupportTab.Title = 'Support';
            app.Image7 = uiimage(app.SupportTab);
            app.Image7.ImageSource = 'support.png';
            app.MATLABShareHubLabel = uilabel(app.ShareHubUIFigure);
            app.MATLABShareHubLabel.Text = 'MATLAB Share Hub';
            app.Image = uiimage(app.ShareHubUIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = '19922387.jpg';
            app.UITable = uitable(app.ShareHubUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.CurrentlySharedLabel = uilabel(app.ShareHubUIFigure);
            app.CurrentlySharedLabel.Visible = 'off';
            app.CurrentlySharedLabel.Text = 'Currently Shared';
            % Show the figure after all components are created
            app.ShareHubUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShareHub

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShareHubUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShareHubUIFigure)
        end
    end
end